classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageUIFigure                matlab.ui.Figure
        TimeSliderLabel              matlab.ui.control.Label
        TimeSlider                   matlab.ui.control.Slider
        RmmEditFieldLabel            matlab.ui.control.Label
        RmmEditField                 matlab.ui.control.NumericEditField
        UIAxes                       matlab.ui.control.UIAxes
        Load2DimagefromscriptButton  matlab.ui.control.Button
        ZaznaczROIoRwmmButton        matlab.ui.control.Button
        LoadnewmodelButton           matlab.ui.control.Button
    end

    properties (Access = public)
        SegImage Image % Image that was selected by user
    end
    
    methods (Access = private)
        
        function displayImage(app,t,z)
            app.UIAxes.XLim = [0 app.SegImage.dim(2)];
            app.UIAxes.YLim = [0 app.SegImage.dim(1)];
            imagesc(abs(squeeze(app.SegImage.voxels(t,z,:,:))),'Parent',app.UIAxes,'XData',[0 50], 'YData', [0 200]);
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.Visible = 'off';
            app.UIAxes.Colormap = gray(256);
            app.SegImage = Image(1,1,1,1);      

        end

        % Button pushed function: Load2DimagefromscriptButton
        function Load2DimagefromscriptButtonPushed(app, event)
            [fileName,filePath] = uigetfile('*.m','Select an image file');      
            if isequal(fileName, 0)
                uialert(app.ImageUIFigure, 'Naciśnięto Anuluj. Wybierz skrypt' , 'Image Error');
                return
            else
                uialert(app.ImageUIFigure, 'Wybano skrypt. Poczekaj na załadowanie się obrazu','Informacja','Icon', 'info');
                app.SegImage = read2DImageFromScript(app.SegImage,filePath,fileName);
                displayImage(app,1,1);
                hold(app.UIAxes,'on');
                plot(app.UIAxes,app.SegImage.imageOrigin(2),app.SegImage.imageOrigin(1),'r+', 'MarkerSize', 10);
                hold(app.UIAxes,'off');
                app.TimeSlider.Limits = [0 app.SegImage.dim(4)];
                app.TimeSlider.MajorTicks = (0:20:app.SegImage.dim(4));
                
            end
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            t = event.Value;
            t = round(t);
            if t<1 
                t=1;
            end
            z = app.TimeSlider.Position;
            displayImage(app,t,1);
            hold(app.UIAxes,'on');
            plot(app.UIAxes,app.SegImage.imageOrigin(2),app.SegImage.imageOrigin(1),'r+', 'MarkerSize', 10);
            hold(app.UIAxes,'off');       
        end

        % Button pushed function: LoadnewmodelButton
        function LoadnewmodelButtonPushed(app, event)
            %th = 0:pi/50:2*pi;
            th = linspace(0,2*pi);
            xunit = 20 * cos(th) + app.SegImage.imageOrigin(2);
            yunit = 20 * sin(th) * (app.SegImage.dim(1)/app.SegImage.dim(2)) + app.SegImage.imageOrigin(1);
            hold(app.UIAxes);
            plot(app.UIAxes, xunit, yunit);
%           hold(app.UIAxes);
%           plot(app.UIAxes,app.SegImage.imageOrigin(2),app.SegImage.imageOrigin(1),'o', 'MarkerSize', 30);
        end

        % Button pushed function: ZaznaczROIoRwmmButton
        function ZaznaczROIoRwmmButtonPushed(app, event)
            RinMM = app.RmmEditField.Value;
           % RinPixels = 
            th = linspace(0,2*pi);
            xunit = RinPixels * cos(th) + app.SegImage.imageOrigin(2);
            yunit = RinPixels * sin(th) + app.SegImage.imageOrigin(1);
            hold(app.UIAxes);
            plot(app.UIAxes, xunit, yunit);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageUIFigure = uifigure;
            app.ImageUIFigure.Name = 'Image';
            app.TimeSliderLabel = uilabel(app.ImageUIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.ImageUIFigure);
            app.TimeSlider.MajorTicks = [0 20 40 60 80 100];
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
            app.RmmEditFieldLabel = uilabel(app.ImageUIFigure);
            app.RmmEditFieldLabel.Text = 'R [mm]';
            app.RmmEditField = uieditfield(app.ImageUIFigure, 'numeric');
            app.RmmEditField.Limits = [0 15];
            app.UIAxes = uiaxes(app.ImageUIFigure);
            title(app.UIAxes, 'Image')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {'[ ]'};
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {'[ ]'};
            app.Load2DimagefromscriptButton = uibutton(app.ImageUIFigure, 'push');
            app.Load2DimagefromscriptButton.ButtonPushedFcn = createCallbackFcn(app, @Load2DimagefromscriptButtonPushed, true);
            app.Load2DimagefromscriptButton.Text = 'Load 2D image from script';
            app.ZaznaczROIoRwmmButton = uibutton(app.ImageUIFigure, 'push');
            app.ZaznaczROIoRwmmButton.ButtonPushedFcn = createCallbackFcn(app, @ZaznaczROIoRwmmButtonPushed, true);
            app.ZaznaczROIoRwmmButton.Text = 'Zaznacz ROI o R w mm';
            app.LoadnewmodelButton = uibutton(app.ImageUIFigure, 'push');
            app.LoadnewmodelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadnewmodelButtonPushed, true);
            app.LoadnewmodelButton.Text = 'Load new model';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageUIFigure)
        end
    end
end